classdef ALIIFAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DefineSampleAreaandBinariseByThresholdingPanel  matlab.ui.container.Panel
        SaveParametersButton            matlab.ui.control.Button
        LBCellAreaSlider_2              matlab.ui.control.Slider
        LBCellAreaSliderLabel_2         matlab.ui.control.Label
        BinariseCellShapeSliderLabel_2  matlab.ui.control.Label
        BinariseCellShapeSlider_2       matlab.ui.control.Slider
        ExportStatsButton               matlab.ui.control.Button
        ExportImagesButton              matlab.ui.control.Button
        SelectPolygonContainingtheSampleButton  matlab.ui.control.Button
        LBCellAreaSlider                matlab.ui.control.Slider
        LBCellAreaSliderLabel           matlab.ui.control.Label
        BinariseCellShapeSliderLabel    matlab.ui.control.Label
        BinariseCellShapeSlider         matlab.ui.control.Slider
        BackgroundCorrectTilesWithEmptyTilesAveragePanel  matlab.ui.container.Panel
        MbyNtilesusedEditField          matlab.ui.control.NumericEditField
        MbyNtilesusedEditFieldLabel     matlab.ui.control.Label
        BckdcornerDropDown              matlab.ui.control.DropDown
        BckdcornerDropDownLabel         matlab.ui.control.Label
        TiledArraySizeYEditField        matlab.ui.control.NumericEditField
        TiledArraySizeYEditFieldLabel   matlab.ui.control.Label
        TiledArraySizeXEditField        matlab.ui.control.NumericEditField
        TiledArraySizeXEditFieldLabel   matlab.ui.control.Label
        CorrectDataButton               matlab.ui.control.Button
        SizeYof1tilepixelsEditField     matlab.ui.control.NumericEditField
        SizeYof1tilepixelsEditFieldLabel  matlab.ui.control.Label
        SizeXof1tilepixelsEditField     matlab.ui.control.NumericEditField
        SizeXof1tilepixelsEditFieldLabel  matlab.ui.control.Label
        LoadImageDataPanel              matlab.ui.container.Panel
        LoadParametersFileifneededButton  matlab.ui.control.Button
        LoadMultitiforOMEfilefor2ndimagingchannelButton  matlab.ui.control.Button
        LoadMultitiforOMEfileforNPStainButton  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PathName
        FileName
        FileName2
        plane
        pixel
        plane2
        profile
        newPlane
        newPlaneCh2
        profile2
        BW2
        BWCh2
        newMask
        newMaskCh2
        tileSizeX
        tileSizeY
        tileNbX
        tileNbY
        choiceCorner
        cornerUse
        mask
        binslider1
        binslider2
        LBslider1
        LBslider2
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMultitiforOMEfileforNPStainButton
        function LoadMultitiforOMEfileforNPStainButtonPushed(app, event)
            filterspec = {'*.czi;*.nd2;*.oir;*.oif;*.lif','OME files'};
            [app.FileName,app.PathName] = uigetfile(filterspec);
            [im,specs]=readOMEAlternativeGUI(app.UIAxes2,[app.PathName app.FileName]);
            app.plane=double(im);
            app.pixel=specs.ScaleX;
            app.tileSizeX=512;
            app.tileSizeY=512;
            app.tileNbX=25;
            app.tileNbY=25;
            app.choiceCorner='UL';
            app.cornerUse=2;
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,log(app.plane));
            colormap(app.UIAxes,gfp(length(unique(log(app.plane(:))))))
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
        end

        % Button pushed function: 
        % LoadMultitiforOMEfilefor2ndimagingchannelButton
        function LoadMultitiforOMEfilefor2ndimagingchannelButtonPushed(app, event)
            filterspec = {'*.czi;*.nd2;*.oir;*.oif;*.lif','OME files'};
            [app.FileName2,app.PathName] = uigetfile(filterspec);
            [im,specs]=readOMEAlternativeGUI(app.UIAxes2,[app.PathName app.FileName2]);
            app.plane2=double(im);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,log(app.plane2));
            colormap(app.UIAxes,yfp(length(unique(log(app.plane2(:))))))
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane2,2)])
            ylim(app.UIAxes,[1 size(app.plane2,1)])
        end

        % Value changed function: SizeXof1tilepixelsEditField
        function SizeXof1tilepixelsEditFieldValueChanged(app, event)
            value = app.SizeXof1tilepixelsEditField.Value;
            app.tileSizeX=value;
        end

        % Value changed function: SizeYof1tilepixelsEditField
        function SizeYof1tilepixelsEditFieldValueChanged(app, event)
            value = app.SizeYof1tilepixelsEditField.Value;
            app.tileSizeY=value;
        end

        % Value changed function: TiledArraySizeXEditField
        function TiledArraySizeXEditFieldValueChanged(app, event)
            value = app.TiledArraySizeXEditField.Value;
            app.tileNbX=value;
        end

        % Value changed function: TiledArraySizeYEditField
        function TiledArraySizeYEditFieldValueChanged(app, event)
            value = app.TiledArraySizeYEditField.Value;
            app.tileNbY=value;
        end

        % Value changed function: BckdcornerDropDown
        function BckdcornerDropDownValueChanged(app, event)
            value = app.BckdcornerDropDown.Value;
            app.choiceCorner=value;
        end

        % Value changed function: MbyNtilesusedEditField
        function MbyNtilesusedEditFieldValueChanged(app, event)
            value = app.MbyNtilesusedEditField.Value;
            app.cornerUse=value;
        end

        % Button pushed function: CorrectDataButton
        function CorrectDataButtonPushed(app, event)
            app.profile=[];
            if strcmp(app.choiceCorner,'UL')
                k=1;
                for i=1:app.cornerUse
                    for j=1:app.cornerUse
                        app.profile(:,:,k)=app.plane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'BL')
                k=1;
                for i=app.tileNbY-app.cornerUse:app.tileNbY
                    for j=1:app.cornerUse
                        app.profile(:,:,k)=app.plane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'UR')
                k=1;
                for i=1:app.cornerUse
                    for j=app.tileNbX-app.cornerUse:app.tileNbX
                        app.profile(:,:,k)=app.plane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'BR')
                k=1;
                for i=app.tileNbY-app.cornerUse:app.tileNbY
                    for j=app.tileNbX-app.cornerUse:app.tileNbX
                        app.profile(:,:,k)=app.plane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end

            end
            aveProfile=mean(app.profile,3);
            %%%%%%%%
                app.profile2=[];
            if strcmp(app.choiceCorner,'UL')
                k=1;
                for i=1:app.cornerUse
                    for j=1:app.cornerUse
                        app.profile2(:,:,k)=app.plane2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'BL')
                k=1;
                for i=app.tileNbY-app.cornerUse:app.tileNbY
                    for j=1:app.cornerUse
                        app.profile2(:,:,k)=app.plane2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'UR')
                k=1;
                for i=1:app.cornerUse
                    for j=app.tileNbX-app.cornerUse:app.tileNbX
                        app.profile2(:,:,k)=app.plane2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end
            elseif strcmp(app.choiceCorner,'BR')
                k=1;
                for i=app.tileNbY-app.cornerUse:app.tileNbY
                    for j=app.tileNbX-app.cornerUse:app.tileNbX
                        app.profile2(:,:,k)=app.plane2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j);
                        k=k+1;
                    end
                end

            end
            aveProfile2=mean(app.profile2,3);
            %%%%%%%%%%%%%%%%
            % subtract average illumination profile
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'Correcting Data...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            app.newPlane=[];
            k=1;
            for i=1:app.tileNbY
            for j=1:app.tileNbX
            app.newPlane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j)=double(app.plane(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j))./aveProfile;
            ph.XData = [0 k/(app.tileNbY*app.tileNbX)  k/(app.tileNbY*app.tileNbX) 0];
            th.String = sprintf('Correcting Data...%.0f%%',round(k/(app.tileNbY*app.tileNbX)*100));
            drawnow %update graphics
            k=k+1;
            end
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,app.newPlane);
            colormap(app.UIAxes,gfp(length(unique(log(app.newPlane(:))))))
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            set(app.UIAxes,'clim',[1 5])
            %%%%
            app.newPlaneCh2=[];
                cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'Correcting Data...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            k=1;
            for i=1:app.tileNbY
            for j=1:app.tileNbX
            app.newPlaneCh2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j)=double(app.plane2(1+(i-1)*app.tileSizeY:app.tileSizeY*i,1+(j-1)*app.tileSizeX:app.tileSizeX*j))./aveProfile2;
            ph.XData = [0 k/(app.tileNbY*app.tileNbX)  k/(app.tileNbY*app.tileNbX) 0];
            th.String = sprintf('Correcting Data...%.0f%%',round(k/(app.tileNbY*app.tileNbX)*100));
            drawnow %update graphics
            k=k+1;
            end
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,app.newPlaneCh2);colormap(app.UIAxes,yfp(length(unique(log(app.newPlaneCh2(:))))))
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            set(app.UIAxes,'clim',[1 3])
        end

        % Button pushed function: SelectPolygonContainingtheSampleButton
        function SelectPolygonContainingtheSampleButtonPushed(app, event)
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,app.newPlane);
            colormap(app.UIAxes,gfp(length(unique((app.newPlane(:))))))
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            roi=images.roi.Polygon(app.UIAxes,'Color','c','StripeColor','r');
            draw(roi)
            app.mask=createMask(roi);
        end

        % Value changing function: BinariseCellShapeSlider
        function BinariseCellShapeSliderValueChanging(app, event)
            changingValue = event.Value;
            newMask=(app.mask.*(app.newPlane>changingValue))>0;
            app.newMask=bwmorph(newMask,'clean');
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            newPlane=app.newMask.*app.newPlane+(~app.newMask);
            imagesc(app.UIAxes,log(newPlane));
            set(app.UIAxes,'clim',[0 max(log(newPlane(:)))])
       
        end

        % Value changing function: LBCellAreaSlider
        function LBCellAreaSliderValueChanging(app, event)
            changingValue = event.Value;
            CC = bwconncomp(app.newMask);
            stats = regionprops(CC, 'Area'); 
            idx = find([stats.Area] > (changingValue)); 
            app.BW2 = ismember(labelmatrix(CC), idx);
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            newPlane=app.BW2.*app.newPlane+(~app.BW2);
            imagesc(app.UIAxes,log(newPlane));
            set(app.UIAxes,'clim',[0 max(log(newPlane(:)))])
        end

        % Value changing function: BinariseCellShapeSlider_2
        function BinariseCellShapeSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            newMask=(app.mask.*(app.newPlaneCh2>changingValue))>0;
            app.newMaskCh2=bwmorph(newMask,'clean');
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            imagesc(app.UIAxes,app.newMaskCh2);
            newPlane=app.newMaskCh2.*app.newPlaneCh2+(~app.newMaskCh2);
            imagesc(app.UIAxes,log(newPlane));
            set(app.UIAxes,'clim',[0 max(log(newPlane(:)))])
        end

        % Value changing function: LBCellAreaSlider_2
        function LBCellAreaSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            CC = bwconncomp(app.newMaskCh2);
            stats = regionprops(CC, 'Area'); 
            idx = find([stats.Area] > (changingValue)); 
            app.BWCh2 = ismember(labelmatrix(CC), idx);
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.plane,2)])
            ylim(app.UIAxes,[1 size(app.plane,1)])
            newPlane=app.BWCh2.*app.newPlaneCh2+(~app.BWCh2);
            imagesc(app.UIAxes,log(newPlane));
            set(app.UIAxes,'clim',[0 max(log(newPlane(:)))])
        end

        % Button pushed function: ExportImagesButton
        function ExportImagesButtonPushed(app, event)
             numpixmm=1000/double(app.pixel);
             cla(app.UIAxes);
             colorbar(app.UIAxes,'off')
             app.UIAxes.YDir='reverse';
             xlabel(app.UIAxes,'')
             ylabel(app.UIAxes,'')
             xlim(app.UIAxes,[1 size(app.plane,2)])
             ylim(app.UIAxes,[1 size(app.plane,1)])
             imagesc(app.UIAxes,app.BW2);
             colormap(app.UIAxes,gray)
             hold(app.UIAxes,"on");
             plot(app.UIAxes,size(app.BW2,2)-numpixmm-app.tileSizeX:size(app.BW2,2)-app.tileSizeX-1,ones(numpixmm,1)*size(app.BW2,1)-app.tileSizeX,'LineWidth',10,'Color',[1 1 1])
             hold(app.UIAxes,"on");
             set(app.UIAxes,'clim',[0 1])
             exportgraphics(app.UIAxes,[app.PathName 'BinaryMask_' app.FileName(1:end-4) '.png']) 
             %%%%
             cla(app.UIAxes);
             colorbar(app.UIAxes,'off')
             app.UIAxes.YDir='reverse';
             xlabel(app.UIAxes,'')
             ylabel(app.UIAxes,'')
             xlim(app.UIAxes,[1 size(app.plane,2)])
             ylim(app.UIAxes,[1 size(app.plane,1)])
             imagesc(app.UIAxes,(app.newPlane));colormap(app.UIAxes,gfp(length(unique((app.newPlane(:))))))
             c=colorbar(app.UIAxes)
             c.Label.String='Intensity Covid (A.U.)'
             set(c,'FontSize',10)
             set(app.UIAxes,'clim',[1 5])
             set(app.UIAxes,'Color',[1 1 1]);
             hold(app.UIAxes,"on");
             plot(app.UIAxes,size(app.BW2,2)-numpixmm-app.tileSizeX:size(app.BW2,2)-app.tileSizeX-1,ones(numpixmm,1)*size(app.BW2,1)-app.tileSizeX,'LineWidth',10,'Color',[1 1 1])
             exportgraphics(app.UIAxes,[app.PathName 'CorrectedNPimageNew_' app.FileName(1:end-4) '.png']) 
             %%%%
             cla(app.UIAxes);
             colorbar(app.UIAxes,'off')
             app.UIAxes.YDir='reverse';
             xlabel(app.UIAxes,'')
             ylabel(app.UIAxes,'')
             xlim(app.UIAxes,[1 size(app.plane,2)])
             ylim(app.UIAxes,[1 size(app.plane,1)])
             imagesc(app.UIAxes,app.BWCh2);colormap(app.UIAxes,gray)
             hold(app.UIAxes,"on");
             plot(app.UIAxes,size(app.BWCh2,2)-numpixmm-app.tileSizeX:size(app.BWCh2,2)-app.tileSizeX-1,ones(numpixmm,1)*size(app.BWCh2,1)-app.tileSizeX,'LineWidth',10,'Color',[1 1 1])
             hold(app.UIAxes,"on");
             set(app.UIAxes,'clim',[0 1])
             exportgraphics(app.UIAxes,[app.PathName 'BinaryMaskCh2_' app.FileName(1:end-4) '.png']) 
             %%%%
              cla(app.UIAxes);
             colorbar(app.UIAxes,'off')
             app.UIAxes.YDir='reverse';
             xlabel(app.UIAxes,'')
             ylabel(app.UIAxes,'')
             xlim(app.UIAxes,[1 size(app.plane,2)])
             ylim(app.UIAxes,[1 size(app.plane,1)])
             imagesc(app.UIAxes,(app.newPlaneCh2));colormap(app.UIAxes,yfp(length(unique((app.newPlane(:))))));
             c=colorbar(app.UIAxes)
             c.Label.String='Intensity Covid (A.U.)'
             set(c,'FontSize',10)
             set(app.UIAxes,'clim',[1 3])
             set(app.UIAxes,'Color',[1 1 1]);
             hold(app.UIAxes,"on");
             plot(app.UIAxes,size(app.BW2,2)-numpixmm-app.tileSizeX:size(app.BW2,2)-app.tileSizeX-1,ones(numpixmm,1)*size(app.BW2,1)-app.tileSizeX,'LineWidth',10,'Color',[1 1 1])
             exportgraphics(app.UIAxes,[app.PathName 'CorrectedimageNewCh2_' app.FileName(1:end-4) '.png']) 
        end

        % Button pushed function: ExportStatsButton
        function ExportStatsButtonPushed(app, event)
        PercentageAreaCoverage=(length(find(app.BW2))/length(find(app.mask)))*100;
        PercentageAreaCoverageCh2=(length(find(app.BWCh2))/length(find(app.mask)))*100;
        overlapCh2NPmask=app.BWCh2.*app.BW2;
        PercentageAreaCoverageOverlap=(length(find(overlapCh2NPmask))/length(find(app.mask)))*100;
        statsWithinCovidMask=regionprops('table',app.BW2,app.newPlane,'PixelValues'); 
        statsWithinCovidMaskCh2=regionprops('table',app.BWCh2,app.newPlaneCh2,'PixelValues');
        %%%%
        in=0;
        pixelIntCovid=[];
        for i=1:size(statsWithinCovidMask,1)
        pixelIntCovid(1+in:in+length(statsWithinCovidMask.PixelValues{i}),1)=statsWithinCovidMask.PixelValues{i};
        in=in+length(statsWithinCovidMask.PixelValues{i});
        end
        meanIntCovid=mean(pixelIntCovid);     
        medianIntCovid=median(pixelIntCovid);   
        stdIntCovid=mean(pixelIntCovid);   
        maxIntCovid=max(pixelIntCovid);   
        modeIntCovid=mode(pixelIntCovid); 
        in=0;
        pixelIntCh2=[];
        for i=1:size(statsWithinCovidMaskCh2,1)
        pixelIntCh2(1+in:in+length(statsWithinCovidMaskCh2.PixelValues{i}),1)=statsWithinCovidMaskCh2.PixelValues{i};
        in=in+length(statsWithinCovidMaskCh2.PixelValues{i});
        end
        meanIntCh2=mean(pixelIntCh2);     
        medianIntCh2=median(pixelIntCh2);   
        stdIntCh2=mean(pixelIntCh2);   
        maxIntCh2=max(pixelIntCh2);   
        modeIntCh2=mode(pixelIntCh2); 
        %%%%
        T = table(PercentageAreaCoverage);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','PercentageAreaCoverageNP'); 
        T = table(PercentageAreaCoverageCh2);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','PercentageAreaCoverageCh2');
        T = table(PercentageAreaCoverageOverlap);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','PercentageOverlapCh2NP');    
        T=table(meanIntCovid,medianIntCovid,stdIntCovid,maxIntCovid,modeIntCovid);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','WholeAreaPropsCovidCh');
        T=table(meanIntCh2,medianIntCh2,stdIntCh2,maxIntCh2,modeIntCh2);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','WholeAreaPropsCh2');
        [yCovidHistogram,xCovidHistogram]=hist(pixelIntCovid,1000);
        T=table(xCovidHistogram',yCovidHistogram');
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','HistEnvPixelIntensitiesCovidCh');
        [yCh2Histogram,xCh2Histogram]=hist(pixelIntCh2,1000);
        T=table(xCh2Histogram',yCh2Histogram');
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','HistEnvPixelIntensitiesCh2');  
        pixIntCovid2=reshape(pixelIntCovid(1:length(pixelIntCovid)-rem(length(pixelIntCovid),100)),[(length(pixelIntCovid)-rem(length(pixelIntCovid),100))/100,100]);
        T=table(pixIntCovid2);
        writetable(T,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','PixelIntensitiesCovidChannel');
         
         % for each cluster find props
         CC = bwconncomp(app.BW2);
         stats = regionprops('table',CC,app.newPlane,'Area','Eccentricity','MaxIntensity','MeanIntensity','Centroid');
         stats2=regionprops('table',app.mask,'Centroid');
         stats.DistToCenter=((stats2.Centroid(1)-stats.Centroid(:,1)).^2+(stats2.Centroid(2)-stats.Centroid(:,2)).^2).^0.5;     
         % for each cluster find props
         CC2 = bwconncomp(app.BWCh2);
         statsCh2 = regionprops('table',CC2,app.newPlaneCh2,'Area','Eccentricity','MaxIntensity','MeanIntensity','Centroid');
         statsCh2.DistToCenterCh2=((stats2.Centroid(1)-statsCh2.Centroid(:,1)).^2+(stats2.Centroid(2)-statsCh2.Centroid(:,2)).^2).^0.5;     
         % save data
        writetable(stats,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','CovidClustersProps');
        writetable(statsCh2,[app.PathName 'NPandCh2Stats_' app.FileName(1:end-4) '.xlsx'],'Sheet','Ch2ClustersProps');


        end

        % Value changed function: BinariseCellShapeSlider
        function BinariseCellShapeSliderValueChanged(app, event)
            value = app.BinariseCellShapeSlider.Value;
            app.binslider1=value;
        end

        % Value changed function: BinariseCellShapeSlider_2
        function BinariseCellShapeSlider_2ValueChanged(app, event)
            value = app.BinariseCellShapeSlider_2.Value;
            app.binslider2=value;
        end

        % Value changed function: LBCellAreaSlider
        function LBCellAreaSliderValueChanged(app, event)
            value = app.LBCellAreaSlider.Value;
            app.LBslider1=value;
        end

        % Value changed function: LBCellAreaSlider_2
        function LBCellAreaSlider_2ValueChanged(app, event)
            value = app.LBCellAreaSlider_2.Value;
            app.LBslider2=value;
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
        tileSizeX=app.tileSizeX;
        tileSizeY=app.tileSizeY;
        tileNbX=app.tileNbX;
        tileNbY=app.tileNbY;
        choiceCorner=app.choiceCorner;
        cornerUse=app.cornerUse;
        binslider1=app.binslider1;
        binslider2=app.binslider2;
        LBslider1=app.LBslider1;
        LBslider2=app.LBslider2;
        save([app.PathName app.FileName(1:end-4) '_AnalysisParameters.mat'],'tileSizeX','tileSizeY','tileNbX','tileNbY',"choiceCorner","cornerUse","binslider1","binslider2","LBslider1","LBslider2")
        end

        % Button pushed function: LoadParametersFileifneededButton
        function LoadParametersFileifneededButtonPushed(app, event)
            filterspec = {'*.mat','Select Parameter file to load'};
            [f, p] = uigetfile(filterspec);
            load([p f]);
            app.tileSizeX=tileSizeX;
            app.SizeXof1tilepixelsEditField.Value=tileSizeX;
            app.tileSizeY=tileSizeY;
            app.SizeYof1tilepixelsEditField.Value=tileSizeY;
            app.tileNbX=tileNbX;
            app.TiledArraySizeXEditField.Value=tileNbX;
            app.tileNbY=tileNbY;
            app.TiledArraySizeYEditField.Value=tileNbY;
            app.choiceCorner=choiceCorner;
            app.BckdcornerDropDown.Value=choiceCorner;
            app.cornerUse=cornerUse;
            app.MbyNtilesusedEditField.Value=cornerUse;
            app.binslider1=binslider1;
            app.BinariseCellShapeSlider.Value=binslider1;
            app.binslider2=binslider2;
            app.BinariseCellShapeSlider_2.Value=binslider2;
            app.LBslider1=LBslider1;
            app.LBCellAreaSlider.Value=LBslider1;
            app.LBslider2=LBslider2;
            app.LBCellAreaSlider_2.Value=LBslider2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.LoadImageDataPanel = uipanel(app.UIFigure);
            app.LoadImageDataPanel.Title = 'Load Image Data';
            app.LoadMultitiforOMEfileforNPStainButton = uibutton(app.LoadImageDataPanel, 'push');
            app.LoadMultitiforOMEfileforNPStainButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMultitiforOMEfileforNPStainButtonPushed, true);
            app.LoadMultitiforOMEfileforNPStainButton.Text = 'Load Multi-tif or OME file for NP Stain';
            app.LoadMultitiforOMEfilefor2ndimagingchannelButton = uibutton(app.LoadImageDataPanel, 'push');
            app.LoadMultitiforOMEfilefor2ndimagingchannelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMultitiforOMEfilefor2ndimagingchannelButtonPushed, true);
            app.LoadMultitiforOMEfilefor2ndimagingchannelButton.Text = 'Load Multi-tif or OME file for 2nd imaging channel';
            app.LoadParametersFileifneededButton = uibutton(app.LoadImageDataPanel, 'push');
            app.LoadParametersFileifneededButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersFileifneededButtonPushed, true);
            app.LoadParametersFileifneededButton.Text = 'Load Parameters File (if needed) ';
            app.BackgroundCorrectTilesWithEmptyTilesAveragePanel = uipanel(app.UIFigure);
            app.BackgroundCorrectTilesWithEmptyTilesAveragePanel.Title = 'Background Correct Tiles With Empty Tiles Average ';
            app.SizeXof1tilepixelsEditFieldLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.SizeXof1tilepixelsEditFieldLabel.Text = 'Size X of 1 tile (pixels)';
            app.SizeXof1tilepixelsEditField = uieditfield(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'numeric');
            app.SizeXof1tilepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @SizeXof1tilepixelsEditFieldValueChanged, true);
            app.SizeXof1tilepixelsEditField.Value = 512;
            app.SizeYof1tilepixelsEditFieldLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.SizeYof1tilepixelsEditFieldLabel.Text = 'Size Y of 1 tile (pixels)';
            app.SizeYof1tilepixelsEditField = uieditfield(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'numeric');
            app.SizeYof1tilepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @SizeYof1tilepixelsEditFieldValueChanged, true);
            app.SizeYof1tilepixelsEditField.Value = 512;
            app.CorrectDataButton = uibutton(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'push');
            app.CorrectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectDataButtonPushed, true);
            app.CorrectDataButton.Text = 'Correct Data  ';
            app.TiledArraySizeXEditFieldLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.TiledArraySizeXEditFieldLabel.Text = 'Tiled Array Size X';
            app.TiledArraySizeXEditField = uieditfield(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'numeric');
            app.TiledArraySizeXEditField.ValueChangedFcn = createCallbackFcn(app, @TiledArraySizeXEditFieldValueChanged, true);
            app.TiledArraySizeXEditField.Value = 25;
            app.TiledArraySizeYEditFieldLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.TiledArraySizeYEditFieldLabel.Text = 'Tiled Array Size Y';
            app.TiledArraySizeYEditField = uieditfield(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'numeric');
            app.TiledArraySizeYEditField.ValueChangedFcn = createCallbackFcn(app, @TiledArraySizeYEditFieldValueChanged, true);
            app.TiledArraySizeYEditField.Value = 25;
            app.BckdcornerDropDownLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.BckdcornerDropDownLabel.Text = 'Bckd corner';
            app.BckdcornerDropDown = uidropdown(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.BckdcornerDropDown.Items = {'UL', 'BL', 'UR', 'BR'};
            app.BckdcornerDropDown.ValueChangedFcn = createCallbackFcn(app, @BckdcornerDropDownValueChanged, true);
            app.BckdcornerDropDown.Value = 'UL';
            app.MbyNtilesusedEditFieldLabel = uilabel(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel);
            app.MbyNtilesusedEditFieldLabel.Text = 'M by N tiles used';
            app.MbyNtilesusedEditField = uieditfield(app.BackgroundCorrectTilesWithEmptyTilesAveragePanel, 'numeric');
            app.MbyNtilesusedEditField.ValueChangedFcn = createCallbackFcn(app, @MbyNtilesusedEditFieldValueChanged, true);
            app.MbyNtilesusedEditField.Value = 2;
            app.DefineSampleAreaandBinariseByThresholdingPanel = uipanel(app.UIFigure);
            app.DefineSampleAreaandBinariseByThresholdingPanel.Title = 'Define Sample Area and Binarise By Thresholding';
            app.BinariseCellShapeSlider = uislider(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.BinariseCellShapeSlider.Limits = [0 5];
            app.BinariseCellShapeSlider.ValueChangedFcn = createCallbackFcn(app, @BinariseCellShapeSliderValueChanged, true);
            app.BinariseCellShapeSlider.ValueChangingFcn = createCallbackFcn(app, @BinariseCellShapeSliderValueChanging, true);
            app.BinariseCellShapeSlider.Value = 1.3;
            app.BinariseCellShapeSliderLabel = uilabel(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.BinariseCellShapeSliderLabel.Text = 'Binarise Ch1';
            app.LBCellAreaSliderLabel = uilabel(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.LBCellAreaSliderLabel.Text = 'LB Cluster Area (\mum^{2})';
            app.LBCellAreaSlider = uislider(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.LBCellAreaSlider.Limits = [0 1000];
            app.LBCellAreaSlider.MajorTicks = [0 200 400 600 800 1000];
            app.LBCellAreaSlider.ValueChangedFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanged, true);
            app.LBCellAreaSlider.ValueChangingFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanging, true);
            app.LBCellAreaSlider.Value = 10;
            app.SelectPolygonContainingtheSampleButton = uibutton(app.DefineSampleAreaandBinariseByThresholdingPanel, 'push');
            app.SelectPolygonContainingtheSampleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPolygonContainingtheSampleButtonPushed, true);
            app.SelectPolygonContainingtheSampleButton.Text = 'Select Polygon Containing the Sample';
            app.ExportImagesButton = uibutton(app.DefineSampleAreaandBinariseByThresholdingPanel, 'push');
            app.ExportImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImagesButtonPushed, true);
            app.ExportImagesButton.Text = 'Export Images';
            app.ExportStatsButton = uibutton(app.DefineSampleAreaandBinariseByThresholdingPanel, 'push');
            app.ExportStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStatsButtonPushed, true);
            app.ExportStatsButton.Text = 'Export Stats';
            app.BinariseCellShapeSlider_2 = uislider(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.BinariseCellShapeSlider_2.Limits = [0 5];
            app.BinariseCellShapeSlider_2.ValueChangedFcn = createCallbackFcn(app, @BinariseCellShapeSlider_2ValueChanged, true);
            app.BinariseCellShapeSlider_2.ValueChangingFcn = createCallbackFcn(app, @BinariseCellShapeSlider_2ValueChanging, true);
            app.BinariseCellShapeSlider_2.Value = 1.03;
            app.BinariseCellShapeSliderLabel_2 = uilabel(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.BinariseCellShapeSliderLabel_2.Text = 'Binarise Ch2';
            app.LBCellAreaSliderLabel_2 = uilabel(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.LBCellAreaSliderLabel_2.Text = 'LB Cluster Area';
            app.LBCellAreaSlider_2 = uislider(app.DefineSampleAreaandBinariseByThresholdingPanel);
            app.LBCellAreaSlider_2.Limits = [0 1000];
            app.LBCellAreaSlider_2.MajorTicks = [0 200 400 600 800 1000];
            app.LBCellAreaSlider_2.ValueChangedFcn = createCallbackFcn(app, @LBCellAreaSlider_2ValueChanged, true);
            app.LBCellAreaSlider_2.ValueChangingFcn = createCallbackFcn(app, @LBCellAreaSlider_2ValueChanging, true);
            app.LBCellAreaSlider_2.Value = 0.01;
            app.SaveParametersButton = uibutton(app.DefineSampleAreaandBinariseByThresholdingPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALIIFAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end